classdef appBerd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        TimeAmplitudeButtonGroup   matlab.ui.container.ButtonGroup
        thirtysecs                 matlab.ui.control.RadioButton
        onemin                     matlab.ui.control.RadioButton
        tenmins                    matlab.ui.control.RadioButton
        onehour                    matlab.ui.control.RadioButton
        fourhours                  matlab.ui.control.RadioButton
        SinceStart                 matlab.ui.control.RadioButton
        LogOptionsPanel            matlab.ui.container.Panel
        BrowseButton               matlab.ui.control.Button
        LogPathEditField           matlab.ui.control.EditField
        LogFileNameEditField       matlab.ui.control.EditField
        FilenameLabel              matlab.ui.control.Label
        STARTRESETButton           matlab.ui.control.Button
        STOPButton                 matlab.ui.control.Button
        OffsetOptionsPanel         matlab.ui.container.Panel
        OffsetButton               matlab.ui.control.Button
        EditField_1                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        EditField_5                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
        EditField_7                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        Sensor1Label               matlab.ui.control.Label
        Sensor2Label               matlab.ui.control.Label
        Sensor3Label               matlab.ui.control.Label
        Sensor4Label               matlab.ui.control.Label
        Sensor5Label               matlab.ui.control.Label
        Sensor6Label               matlab.ui.control.Label
        Sensor7Label               matlab.ui.control.Label
        Sensor8Label               matlab.ui.control.Label
        EditField_8                matlab.ui.control.NumericEditField
        ClearOffsetButton          matlab.ui.control.Button
        SerialCOMOptionsPanel      matlab.ui.container.Panel
        COMPortLabel               matlab.ui.control.Label
        COMPortEditField           matlab.ui.control.EditField
        HumidityandTemperatureSensorsPanel  matlab.ui.container.Panel
        HumidityEditFieldLabel     matlab.ui.control.Label
        HumidityEditField          matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel  matlab.ui.control.Label
        TemperatureEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        interval = 30;
        workFlag = 0;
        tableSize = 7;
        logflag = 0;
        rf;
        fid;
        offset = {0,0,0,0,0,0,0,0};
        offset_1 = 0;
        values;
        i =0;
        finalpoint = 0;
        filenamewoID = 'Log_';
        filename;
        S;
        serialopenflag = 0;
        canStop = 1;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.filename = [app.filenamewoID datestr(now, 'yyyymmddHHMMSS')];
            %app.LogFileNameEditField.Value = [app.filename '.txt'];
        end

        % Button pushed function: STARTRESETButton
        function STARTRESETButtonPushed(app, event)
            if(app.workFlag)
                fclose(app.S);
            end
            
            pause(0.25);
            
            app.workFlag = 1;
            app.filename = [app.filenamewoID datestr(now, 'yyyymmddHHMMSS')];
            app.LogFileNameEditField.Value = [app.filename '.txt'];
            
            %COMport = '/dev/tty.usbserial-DN02Z092';
            
            if(app.workFlag)
                %CHECK COMPORT WAS CHOOSED
                COMPort = app.COMPortEditField.Value;
                if isempty(COMPort)
                    f = uifigure;
                    app.filenamenum = app.filenamenum - 1;
                    uialert(f, 'COM Port Not Specified', 'Error')
                else
                    %CHECK IF COMPORT OPENNED
                    app.S = serial(COMPort);
                    fopen(app.S);
                    if (isvalid(app.S) == 0)
                        f = uifigure;
                        app.filenamenum = app.filenamenum - 1;
                        uialert(f, 'COM Port Incorrect', 'Error');
                    else
                        app.serialopenflag = app.serialopenflag + 1;
                        %CHECK IF LOG PATH AND LOG FILE NAME ARE AVAILABLE
                        SS = app.LogPathEditField.Value;
                        SSS = app.LogFileNameEditField.Value;
                        if (isempty(SS) | isempty(SSS))
                            %f = uifigure;
                            %app.filenamenum = app.filenamenum - 1;
                            %uialert(f, 'Data will not be logged', 'Warning');
                            app.logflag = 0;
                        else
                            fullfilename = [SS '/' SSS];
                            app.fid = fopen(fullfilename, 'wt');
                            if (app.fid < 3)
                                %f = uifigure;
                                %app.filenamenum = app.filenamenum - 1;
                                %uialert(f, 'Log File Creation Failed. Data will not be logged', 'Warning');
                                app.logflag = 0;
                            else
                                app.logflag = 1;
                            end
                        end
                        
                        %pause(0.25);
                        
                        app.canStop = 0;
                        
                        %pause(0.25);
                        
                        %DO THE STUFF
                        try
                        line = fgetl(app.S);                 %get data from com port
                        catch 
                            fopen(app.S);
                            line = fgetl(app.S);
                        end
                        
                        app.values = zeros(1, 10);             %start data vector with 9 columns
                        app.i = 1;                            %start data vector index
                        while(line > 0 & app.workFlag)
                            
                            %%GET app.values FROM COM LINE
                            C = strsplit(line);
                            %dest = 1;
                            try
                                for a=0:9
                                    app.values(app.i, a+1) = str2num(char(C((a*4)+3)));     %get useful app.values from com port
                                end
                            catch
                                try
                                    for a=0:9
                                        app.values(app.i, a+1) = str2num(char(C((a*4)+3)));     %get useful app.values from com port
                                    end
                                catch
                                    try
                                        for a=0:9
                                            app.values(app.i, a+1) = str2num(char(C((a*4)+3)));     %get useful app.values from com port
                                        end
                                    catch
                                        true
                                    end
                                end
                            end
                            
                            %%UPDATE HUMIDITY AND TEMPERATURE BOXES
                            app.HumidityEditField.Value = app.values(app.i, 9)/10;
                            app.TemperatureEditField.Value = app.values(app.i, 10)/10;
                            
                            %%PREPARE MOVING PLOT WINDOW
                            app.finalpoint = length(app.values(:,1));             %upper limit follows vector size
                            if(app.interval > 0)
                                newoffset = min(app.i, app.interval)-1;       %window grow in realtime before start moving
                            else
                                newoffset = app.finalpoint - 1;
                            end
                            startpoint = app.finalpoint - newoffset - 1;      %calculate starting point
                            t = 1:app.finalpoint;
                            
                            hold(app.UIAxes);
                            
                            values2plot_1 = app.values(:,1)/10 - app.EditField_1.Value;
                            values2plot_2 = app.values(:,2)/10 - app.EditField_2.Value;
                            values2plot_3 = app.values(:,3)/10 - app.EditField_3.Value;
                            values2plot_4 = app.values(:,4)/10 - app.EditField_4.Value;
                            values2plot_5 = app.values(:,5)/10 - app.EditField_5.Value;
                            values2plot_6 = app.values(:,6)/10 - app.EditField_6.Value;
                            values2plot_7 = app.values(:,7)/10 - app.EditField_7.Value;
                            values2plot_8 = app.values(:,8)/10 - app.EditField_8.Value;
                            
                            plot(app.UIAxes, t, values2plot_1, 'r', ...
                                t, values2plot_2, 'g', ...
                                t, values2plot_3, 'b', ...
                                t, values2plot_4, 'y', ...
                                t, values2plot_5, 'm', ...
                                t, values2plot_6, 'c', ...
                                t, values2plot_7, 'k', ...
                                t, values2plot_8, 'r--');
                            
                            drawnow;
                            app.UIAxes.XLim = [startpoint, app.finalpoint];    %make window 'move'
                            
                            if (app.workFlag == 0)
                                app.canStop = 1;
                                break
                            end
                            
                            %%UPDATE TABLE - THIS TAKES SO MUCH TIME!!!
                            actualTableSize = min(app.i, app.tableSize);
                            if (actualTableSize > 1)
                                for s = 0:actualTableSize-2
                                    %new_s = actualTableSize + 2 - s;
                                    app.UITable.Data(actualTableSize-s, :) = app.UITable.Data(actualTableSize-s-1, :);
                                end
                            end
                            app.UITable.Data(1, :) = app.values(app.i,1:8)/10;
                            
                            %DO BACKUP HERE
                            if (app.logflag)
                                fprintf(app.fid, strcat(num2str(app.values(app.i,1)/10), '\t', ...
                                    num2str(app.values(app.i,2)/10), '\t', ...
                                    num2str(app.values(app.i,3)/10), '\t', ...
                                    num2str(app.values(app.i,4)/10), '\t', ...
                                    num2str(app.values(app.i,5)/10), '\t', ...
                                    num2str(app.values(app.i,6)/10), '\t', ...
                                    num2str(app.values(app.i,7)/10), '\t', ...
                                    num2str(app.values(app.i,8)/10), '\t', ...
                                    num2str(app.values(app.i,9)/10), '\t', ...
                                    num2str(app.values(app.i,10)/10),'\n'  ...
                                    ));
                            end
                            
                            app.canStop = 1;
                            
                            if (app.workFlag)
                                line = fgetl(app.S);                %get next line from com port
                            else
                                line = -1;
                            end
                            app.i = app.i + 1;
                            
                            pause(0.05);
                        end
                    end
                end
            end
        end

        % Selection changed function: TimeAmplitudeButtonGroup
        function TimeAmplitudeButtonGroupSelectionChanged(app, event)
            if (app.thirtysecs.Value)
                app.interval = 30;
            end
            if (app.onemin.Value)
                app.interval = 60;
            end
            if (app.tenmins.Value)
                app.interval = 600;
            end
            if (app.onehour.Value)
                app.interval = 3600;
            end
            if (app.fourhours.Value)
                app.interval = 14400;
            end
            if (app.SinceStart.Value)
                app.interval = -1;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.workFlag = 0;
            
            count = 0; count_max = 1e15;
            tic;
            while ((toc < 3) && (count < count_max) && app.canStop == 1) % always include a failsafe!
                count = count + 1;
            end
            
            if (app.serialopenflag > 0)
                fclose(app.S);
            end
            if (app.logflag)
                fclose(app.fid);
            end
            app.logflag = 0;
            app.serialopenflag = 0;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.LogPathEditField.Value = uigetdir;
        end

        % Button pushed function: OffsetButton
        function OffsetButtonPushed(app, event)
            if (app.serialopenflag > 0)
                app.offset = app.values(app.finalpoint, :)/10;
                app.EditField_1.Value = app.offset(1);
                app.EditField_2.Value = app.offset(2);
                app.EditField_3.Value = app.offset(3);
                app.EditField_4.Value = app.offset(4);
                app.EditField_5.Value = app.offset(5);
                app.EditField_6.Value = app.offset(6);
                app.EditField_7.Value = app.offset(7);
                app.EditField_8.Value = app.offset(8);
            end
        end

        % Button pushed function: ClearOffsetButton
        function ClearOffsetButtonPushed(app, event)
            app.EditField_1.Value = 0;
            app.EditField_2.Value = 0;
            app.EditField_3.Value = 0;
            app.EditField_4.Value = 0;
            app.EditField_5.Value = 0;
            app.EditField_6.Value = 0;
            app.EditField_7.Value = 0;
            app.EditField_8.Value = 0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.workFlag = 0;
            
            count = 0; count_max = 1e15;
            tic;
            while ((toc < 3) && (count < count_max) && app.canStop == 0) % always include a failsafe!
                count = count + 1;
            end
            if (app.serialopenflag > 0)
                app.serialopenflag = 0;
                fclose(app.S);
            end
            if (app.logflag)
                app.logflag = 0;
                fclose(app.fid);
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, false)
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'S6'; 'S7'; 'S8'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false false false true false];
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time / s');
            ylabel(app.UIAxes, 'Displacement / mm');
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XLimMode = 'manual';
            app.TimeAmplitudeButtonGroup = uibuttongroup(app.UIFigure);
            app.TimeAmplitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeAmplitudeButtonGroupSelectionChanged, true);
            app.TimeAmplitudeButtonGroup.Title = 'Time Amplitude';
            app.thirtysecs = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.thirtysecs.Text = '30 Seconds';
            app.thirtysecs.Value = true;
            app.onemin = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.onemin.Text = '1 Minute';
            app.tenmins = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.tenmins.Text = '10 Minutes';
            app.onehour = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.onehour.Text = '1 Hour';
            app.fourhours = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.fourhours.Text = '4 Hours';
            app.SinceStart = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.SinceStart.Text = 'Since Start';
            app.LogOptionsPanel = uipanel(app.UIFigure);
            app.LogOptionsPanel.Title = 'Log Options';
            app.BrowseButton = uibutton(app.LogOptionsPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LogPathEditField = uieditfield(app.LogOptionsPanel, 'text');
            app.LogFileNameEditField = uieditfield(app.LogOptionsPanel, 'text');
            app.LogFileNameEditField.Value = 'Log_aaaammddHHMMSS.txt';
            app.FilenameLabel = uilabel(app.LogOptionsPanel);
            app.FilenameLabel.Text = 'Filename';
            app.STARTRESETButton = uibutton(app.UIFigure, 'push');
            app.STARTRESETButton.ButtonPushedFcn = createCallbackFcn(app, @STARTRESETButtonPushed, true);
            app.STARTRESETButton.Text = 'START/RESET';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.OffsetOptionsPanel = uipanel(app.UIFigure);
            app.OffsetOptionsPanel.Title = 'Offset Options';
            app.OffsetButton = uibutton(app.OffsetOptionsPanel, 'push');
            app.OffsetButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetButtonPushed, true);
            app.OffsetButton.Text = 'Offset';
            app.EditField_1 = uieditfield(app.OffsetOptionsPanel, 'numeric');
            app.EditField_4 = uieditfield(app.OffsetOptionsPanel, 'numeric');
            app.EditField_5 = uieditfield(app.OffsetOptionsPanel, 'numeric');
            app.EditField_6 = uieditfield(app.OffsetOptionsPanel, 'numeric');
            app.EditField_7 = uieditfield(app.OffsetOptionsPanel, 'numeric');
            app.EditField_3 = uieditfield(app.OffsetOptionsPanel, 'numeric');
            app.EditField_2 = uieditfield(app.OffsetOptionsPanel, 'numeric');
            app.Sensor1Label = uilabel(app.OffsetOptionsPanel);
            app.Sensor1Label.Text = 'Sensor 1';
            app.Sensor2Label = uilabel(app.OffsetOptionsPanel);
            app.Sensor2Label.Text = 'Sensor 2';
            app.Sensor3Label = uilabel(app.OffsetOptionsPanel);
            app.Sensor3Label.Text = 'Sensor 3';
            app.Sensor4Label = uilabel(app.OffsetOptionsPanel);
            app.Sensor4Label.Text = 'Sensor 4';
            app.Sensor5Label = uilabel(app.OffsetOptionsPanel);
            app.Sensor5Label.Text = 'Sensor 5';
            app.Sensor6Label = uilabel(app.OffsetOptionsPanel);
            app.Sensor6Label.Text = 'Sensor 6';
            app.Sensor7Label = uilabel(app.OffsetOptionsPanel);
            app.Sensor7Label.Text = 'Sensor 7';
            app.Sensor8Label = uilabel(app.OffsetOptionsPanel);
            app.Sensor8Label.Text = 'Sensor 8 (--)';
            app.EditField_8 = uieditfield(app.OffsetOptionsPanel, 'numeric');
            app.ClearOffsetButton = uibutton(app.OffsetOptionsPanel, 'push');
            app.ClearOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @ClearOffsetButtonPushed, true);
            app.ClearOffsetButton.Text = 'Clear Offset';
            app.SerialCOMOptionsPanel = uipanel(app.UIFigure);
            app.SerialCOMOptionsPanel.Title = 'Serial COM Options';
            app.COMPortLabel = uilabel(app.SerialCOMOptionsPanel);
            app.COMPortLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.SerialCOMOptionsPanel, 'text');
            app.COMPortEditField.Value = 'COM1';
            app.HumidityandTemperatureSensorsPanel = uipanel(app.UIFigure);
            app.HumidityandTemperatureSensorsPanel.Title = 'Humidity and Temperature Sensors';
            app.HumidityEditFieldLabel = uilabel(app.HumidityandTemperatureSensorsPanel);
            app.HumidityEditFieldLabel.Text = 'Humidity';
            app.HumidityEditField = uieditfield(app.HumidityandTemperatureSensorsPanel, 'numeric');
            app.HumidityEditField.ValueDisplayFormat = '%11.6g';
            app.HumidityEditField.Editable = 'off';
            app.TemperatureEditFieldLabel = uilabel(app.HumidityandTemperatureSensorsPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.HumidityandTemperatureSensorsPanel, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%11.6g';
            app.TemperatureEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appBerd()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end